classdef MatlabCode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MixDesignParametersPanel       matlab.ui.container.Panel
        MetakaolinMKkgmEditFieldLabel  matlab.ui.control.Label
        MetakaolinMKkgmEditField       matlab.ui.control.NumericEditField
        SodiumHydroxideSolutionSHSkgmEditFieldLabel  matlab.ui.control.Label
        SodiumHydroxideSolutionSHSkgmEditField  matlab.ui.control.NumericEditField
        SodiumHydroxideSolutionMolaritySHSMMLabel  matlab.ui.control.Label
        SodiumHydroxideSolutionMolaritySHSMMEditField  matlab.ui.control.NumericEditField
        SodiumSilicateSolutionSSSkgmEditFieldLabel  matlab.ui.control.Label
        SodiumSilicateSolutionSSSkgmEditField  matlab.ui.control.NumericEditField
        WaterWkgmEditFieldLabel        matlab.ui.control.Label
        WaterWkgmEditField             matlab.ui.control.NumericEditField
        WatertoSolidratioWSEditFieldLabel  matlab.ui.control.Label
        WatertoSolidratioWSEditField   matlab.ui.control.NumericEditField
        SodiumOxidetoAluminumOxideratioNaOAlOEditFieldLabel  matlab.ui.control.Label
        SodiumOxidetoAluminumOxideratioNaOAlOEditField  matlab.ui.control.NumericEditField
        SilicatoAluminaratioSiOAlOLabel  matlab.ui.control.Label
        SilicatoAluminaratioSiOAlOEditField  matlab.ui.control.NumericEditField
        WatertoSodiumOxideratioHONaOLabel  matlab.ui.control.Label
        WatertoSodiumOxideratioHONaOEditField  matlab.ui.control.NumericEditField
        CoarsetoFineAggregateratioCAFAEditFieldLabel  matlab.ui.control.Label
        CoarsetoFineAggregateratioCAFAEditField  matlab.ui.control.NumericEditField
        SuperplasticizerDosageSPkgmEditFieldLabel  matlab.ui.control.Label
        SuperplasticizerDosageSPkgmEditField  matlab.ui.control.NumericEditField
        CategoricalVariableforPreCuringStrategiesPCCkgmLabel  matlab.ui.control.Label
        CategoricalVariableforPreCuringStrategiesPCCkgmEditField  matlab.ui.control.NumericEditField
        CuringTemperatureCTCEditFieldLabel  matlab.ui.control.Label
        CuringTemperatureCTCEditField  matlab.ui.control.NumericEditField
        AgeDaysEditFieldLabel          matlab.ui.control.Label
        AgeDaysEditField               matlab.ui.control.NumericEditField
        PredictedCompressiveStrengthCSMPaLabel  matlab.ui.control.Label
        PredictButton                  matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        Label                          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            x1 = app.MetakaolinMKkgmEditField.Value;
            x2 = app.SodiumHydroxideSolutionSHSkgmEditField.Value;
            x3 = app.SodiumHydroxideSolutionMolaritySHSMMEditField.Value;
            x4 = app.SodiumSilicateSolutionSSSkgmEditField.Value;
            x5 = app.WaterWkgmEditField.Value;
            x6 = app.WatertoSolidratioWSEditField.Value;
            x7 = app.SodiumOxidetoAluminumOxideratioNaOAlOEditField.Value;
            x8 = app.SilicatoAluminaratioSiOAlOEditField.Value;
            x9 = app.WatertoSodiumOxideratioHONaOEditField.Value;
            x10 = app.CoarsetoFineAggregateratioCAFAEditField.Value;
            x11 = app.SuperplasticizerDosageSPkgmEditField.Value;
            x12 = app.CategoricalVariableforPreCuringStrategiesPCCkgmEditField.Value;
            x13 = app.CuringTemperatureCTCEditField.Value;
            x14 = app.AgeDaysEditField.Value;
            
            y = 0.982418248+ 0.00157127*x1+0.000131323*x2+0.0000231498*x3+0.000283136*x4-0.008793083*x5+10.82395622*x6-30.57554886*x7-11.1330023*x8...
+0.0000126636*x9-13.69234621*x10+3.366626356*x11+(0.014887229*x12)+(0.0000183579*x13)+(0.000012*x14)-(0.00036*x1*x2)+(0.003893*x1*x3)...
+(0.000503*x1*x4)+(0.001226*x1*x5)+(0.000169*x1*x6)+(0.000217*x1*x7)+(0.0006*x1*x8)-(0.000174*x1*x9)...
-(0.0000876*x1*x10)+(0.000321*x1*x11)-(0.000177*x1*x12)+(0.004973*x1*x13)+(0.0000573*x1*x14)...
+(0.011256*x2*x3)-(0.001038*x2*x4)-(0.002501*x2*x5)+(0.000046*x2*x6)+(0.0000714*x2*x7)+(0.000255*x2*x8)...
-(0.001381*x2*x9)+(0.034146*x2*x10)+(0.000222*x2*x11)-(0.000114*x2*x12)-(0.000888*x2*x13)+(0.0000209*x2*x14)...
-(0.024897*x3*x4)-(0.076875*x3*x5)+(29.685515*x3*x6)+(0.00001741*x3*x7)+(0.00004644*x3*x8)+(0.0001627*x3*x9)...
+(0.00007757*x3*x10)-(0.0000762*x3*x11)-(0.00001186*x3*x12)-(0.161935*x3*x13)+(0.00001817*x3*x14)...
-(0.001809*x4*x5)+(0.0000840586*x4*x6)+(0.00016466*x4*x7)+(0.000151171*x4*x8)-(0.012119595*x4*x9)-(0.001464*x4*x10)...
-(0.008606*x4*x11)-(0.000375*x4*x12)+(0.0072752*x4*x13)+(0.0001654*x4*x14)-(0.54394*x5*x6)+(0.6827603*x5*x7)...
-(0.0000204*x5*x8)-(0.0000939*x5*x9)+(0.00001687*x5*x10)-(0.0000129*x5*x11)-(0.0000731*x5*x12)...
-(0.000177*x5*x13)+(0.0015599*x5*x14)+(1.4692051*x6*x7)+(22.306548*x6*x8)+(1.9006966*x6*x9)+(0.0757717*x6*x10)...
-(0.949359*x6*x11)-(0.251866*x6*x12)-(5.447204*x6*x13)-(0.001201*x6*x14)-(0.173644*x7*x8)+(0.00001205*x7*x9)...
+(17.550286*x7*x10)+(0.2174853*x7*x11)-(24.63378*x7*x12)+(0.00001899*x7*x13)+(0.00001531*x7*x14)...
+(0.0000299524*x8*x9)+(0.0000143283*x8*x10)-(0.0000215287*x8*x11)+(3.025958515*x8*x12)+(0.0000373312*x8*x13)...
+(0.0000247301*x8*x14)+(0.000042*x9*x10)-(0.0000675*x9*x11)-(0.0000119*x9*x12)+(0.0000418*x9*x13)...
+(0.0000598*x9*x14)-(0.69727972946635*x10*x11)+(0.456091*x10*x12)+(0.0000779*x10*x13)+(0.0000549041*x10*x14)...
-(0.0000147853*x11*x12)-(0.000133774*x11*x13)-(0.000242491*x11*x14)-(0.0000212649*x12*x13)-(0.0000242265*x12*x14)...
-(0.001261372*x13*x14)-(0.253917537*(x5/x1))+(0.069602291*(x4/x1))-(0.010050256*(x5/(x1+x3+x4)));

app.Label.Text = num2str(y,'%3f');
   app.Label.FontWeight = 'bold';


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.MetakaolinMKkgmEditField.Value = 0 ;
            app.MetakaolinMKkgmEditField.Value = 0 ;
            app.SodiumHydroxideSolutionSHSkgmEditField.Value = 0 ;
            app.SodiumHydroxideSolutionMolaritySHSMMEditField.Value = 0 ;
            app.SodiumSilicateSolutionSSSkgmEditField.Value = 0 ;
            app.WaterWkgmEditField.Value = 0 ;
            app.WatertoSolidratioWSEditField.Value = 0 ;
            app.SodiumOxidetoAluminumOxideratioNaOAlOEditField.Value = 0 ;
            app.SilicatoAluminaratioSiOAlOEditField.Value = 0 ;
            app.WatertoSodiumOxideratioHONaOEditField.Value = 0 ;
            app.CoarsetoFineAggregateratioCAFAEditField.Value = 0 ;
            app.SuperplasticizerDosageSPkgmEditField.Value = 0 ;
            app.CategoricalVariableforPreCuringStrategiesPCCkgmEditField.Value = 0 ;
            app.CuringTemperatureCTCEditField.Value = 0 ;
            app.AgeDaysEditField.Value = 0 ;
            
            app.Label.Text = '---'
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MixDesignParametersPanel = uipanel(app.UIFigure);
            app.MixDesignParametersPanel.Title = 'Mix Design Parameters';
            app.MetakaolinMKkgmEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.MetakaolinMKkgmEditFieldLabel.Text = 'Metakaolin (MK) (kg/m³)';
            app.MetakaolinMKkgmEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.SodiumHydroxideSolutionSHSkgmEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.SodiumHydroxideSolutionSHSkgmEditFieldLabel.Text = 'Sodium Hydroxide Solution (SHS) (kg/m³)';
            app.SodiumHydroxideSolutionSHSkgmEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.SodiumHydroxideSolutionMolaritySHSMMLabel = uilabel(app.MixDesignParametersPanel);
            app.SodiumHydroxideSolutionMolaritySHSMMLabel.Text = 'Sodium Hydroxide Solution Molarity (SHSM) (M)';
            app.SodiumHydroxideSolutionMolaritySHSMMEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.SodiumSilicateSolutionSSSkgmEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.SodiumSilicateSolutionSSSkgmEditFieldLabel.Text = 'Sodium Silicate Solution (SSS) (kg/m³)';
            app.SodiumSilicateSolutionSSSkgmEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.WaterWkgmEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.WaterWkgmEditFieldLabel.Text = 'Water (W) (kg/m³)';
            app.WaterWkgmEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.WatertoSolidratioWSEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.WatertoSolidratioWSEditFieldLabel.Text = 'Water-to-Solid ratio (W/S) ';
            app.WatertoSolidratioWSEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.SodiumOxidetoAluminumOxideratioNaOAlOEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.SodiumOxidetoAluminumOxideratioNaOAlOEditFieldLabel.Text = 'Sodium Oxide-to-Aluminum Oxide ratio (Na₂O/Al₂O₃) ';
            app.SodiumOxidetoAluminumOxideratioNaOAlOEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.SilicatoAluminaratioSiOAlOLabel = uilabel(app.MixDesignParametersPanel);
            app.SilicatoAluminaratioSiOAlOLabel.Text = 'Silica-to-Alumina ratio (SiO₂/Al₂O₃) ';
            app.SilicatoAluminaratioSiOAlOEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.WatertoSodiumOxideratioHONaOLabel = uilabel(app.MixDesignParametersPanel);
            app.WatertoSodiumOxideratioHONaOLabel.Text = 'Water-to-Sodium Oxide ratio (H₂O/Na₂O) ';
            app.WatertoSodiumOxideratioHONaOEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.CoarsetoFineAggregateratioCAFAEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.CoarsetoFineAggregateratioCAFAEditFieldLabel.Text = 'Coarse-to-Fine Aggregate ratio (CA/FA) ';
            app.CoarsetoFineAggregateratioCAFAEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.SuperplasticizerDosageSPkgmEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.SuperplasticizerDosageSPkgmEditFieldLabel.Text = 'Superplasticizer Dosage (SP) (kg/m³)';
            app.SuperplasticizerDosageSPkgmEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.CategoricalVariableforPreCuringStrategiesPCCkgmLabel = uilabel(app.MixDesignParametersPanel);
            app.CategoricalVariableforPreCuringStrategiesPCCkgmLabel.Text = 'Categorical Variable for Pre-Curing Strategies (PCC) (kg/m³)';
            app.CategoricalVariableforPreCuringStrategiesPCCkgmEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.CuringTemperatureCTCEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.CuringTemperatureCTCEditFieldLabel.Text = 'Curing Temperature (CT) (°C)';
            app.CuringTemperatureCTCEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.AgeDaysEditFieldLabel = uilabel(app.MixDesignParametersPanel);
            app.AgeDaysEditFieldLabel.Text = 'Age (Days)';
            app.AgeDaysEditField = uieditfield(app.MixDesignParametersPanel, 'numeric');
            app.PredictedCompressiveStrengthCSMPaLabel = uilabel(app.UIFigure);
            app.PredictedCompressiveStrengthCSMPaLabel.Text = 'Predicted Compressive Strength (CS) (MPa) :';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '---';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabCode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end